classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TextArea_2        matlab.ui.control.TextArea
        TextArea_2Label   matlab.ui.control.Label
        ListBox           matlab.ui.control.ListBox
        openButton        matlab.ui.control.Button
        TextArea          matlab.ui.control.TextArea
        SelectfileButton  matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            P = [0,0,0,0,0];
            spider_plot_R2019b(P,'AxesInterval', 1,'AxesLimits', [0, 0, 0, 0, 0; 5, 5, 5, 5, 5],'AxesHandle', app.UIAxes);      %用spider plot覆蓋UIAxes
            app.ListBox.Items = {};                                                                                             %把右邊的listbox清空
            app.ListBox.ItemsData = {};
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            [filename filepath] = uigetfile({'.wav'},'選擇音檔');                                                                %讀檔按鈕
            if isequal(filename,0)||isequal(filepath,0)                                                                         %有選到檔案才會顯示在textarea
                return;
            else
                app.TextArea.Value=[filepath filename];                                                                         
            end
        end

        % Button pushed function: openButton
        function openButtonPushed(app, event)
            %reserved
        end

        % Clicked callback: ListBox
        function ListBoxClicked(app, event)
            item = event.InteractionInformation.Item;                                                                           %沒用?     
            if isstring(app.ListBox.Value)                                                                                      %避免右邊listbox還是空的狀況下點擊造成錯誤                                                                               
                cla(app.UIAxes);                                                                                                %每次點listbox都會清掉上一個圖表
                spider_plot_R2019b(str2num(app.ListBox.Value),'AxesInterval', 1,'AxesLimits', [0, 0, 0, 0, 0; 5, 5, 5, 5, 5],'AxesHandle', app.UIAxes);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SelectfileButton = uibutton(app.UIFigure, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.openButton = uibutton(app.UIFigure, 'push');
            app.openButton.ButtonPushedFcn = createCallbackFcn(app, @openButtonPushed, true);
            app.openButton.Text = 'open';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Item', 'Item', 'Item', 'Item', 'item', 'item', 'item', 'item', 'item', 'item', 'item', 'item', 'item', 'item', 'item', 'item', 'item', 'item', 'item', 'item'};
            app.ListBox.ClickedFcn = createCallbackFcn(app, @ListBoxClicked, true);
            app.ListBox.Value = 'item';
            app.TextArea_2Label = uilabel(app.UIFigure);
            app.TextArea_2Label.Text = 'Text Area';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end